classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PlaysoundButton               matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        HighFreqGainNumericEditField  matlab.ui.control.NumericEditField
        EditField_3Label              matlab.ui.control.Label
        MidFreqGainNumericEditField   matlab.ui.control.NumericEditField
        EditField_2Label              matlab.ui.control.Label
        LowFreqGainNumericEditField   matlab.ui.control.NumericEditField
        EditFieldLabel                matlab.ui.control.Label
        HighFreqGainSlider            matlab.ui.control.Slider
        Slider3Label                  matlab.ui.control.Label
        MidFreqGainSlider             matlab.ui.control.Slider
        Slider2Label                  matlab.ui.control.Label
        LowFreqGainSlider             matlab.ui.control.Slider
        SliderLabel                   matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end


 properties (Access = public)
    song % Audio data
    Fs % Sampling rate of the audio dat   
 end
    methods (Access = public)
    function startupFcn(app)
        [app.song, app.Fs] = audioread('mj.wav');  % Ensure this file is in your path or specify the full path
        app.LowFreqGainNumericEditField.Value = 0;
        app.MidFreqGainNumericEditField.Value = 0;
        app.HighFreqGainNumericEditField.Value = 0;
        updatePlot(app);  % Initialize your plot
    end
    

 function filtered_signal = high_freq_shelving_filter(input_signal, G, fc, fs, Q)
    % high_freq_shelving_filter applies a high-frequency shelving filter to an audio signal
    % and plots its frequency response.
    %   input_signal: Array of audio samples
    %   G: Gain of the filter. Positive values boost the treble, negative values reduce it.
    %   fc: Cutoff frequency in Hz
    %   fs: Sampling frequency in Hz
    %   Q: Quality factor of the filter

    % Calculate normalized cutoff frequency and other parameters
    omega_c = 2 * pi * fc / fs;
    A = 10^(G/40);
    beta = sqrt(A + A^2 * (1 / Q^2 - 1));

    % Calculate filter coefficients
    b0 = A * ((A + 1) + (A - 1) * cos(omega_c) + beta * sin(omega_c));
    b1 = -2 * A * ((A - 1) + (A + 1) * cos(omega_c));
    b2 = A * ((A + 1) + (A - 1) * cos(omega_c) - beta * sin(omega_c));
    a0 = (A + 1) - (A - 1) * cos(omega_c) + beta * sin(omega_c);
    a1 = 2 * ((A - 1) - (A + 1) * cos(omega_c));
    a2 = (A + 1) - (A - 1) * cos(omega_c) - beta * sin(omega_c);

    % Normalize coefficients
    b = [b0, b1, b2] / a0;
    a = [a0, a1, a2] / a0;

    % Apply the filter
    filtered_signal = filter(b, a, input_signal);
    end
    function filtered_signal = low_freq_shelving_filter(app, input_signal, G, fc, fs, Q)
    % low_freq_shelving_filter applies a low-frequency shelving filter to an audio signal
    % and plots its frequency response.
    %   input_signal: Array of audio samples
    %   G: Gain of the filter. Positive values boost the bass, negative values reduce it.
    %   fc: Cutoff frequency in Hz
    %   fs: Sampling frequency in Hz
    %   Q: Quality factor of the filter

    % Calculate normalized cutoff frequency and other parameters
    omega_c = 2 * pi * fc / fs;
    A = 10^(G/40);
    beta = sqrt(A + A^2 * (1 / Q^2 - 1));

    % Calculate filter coefficients
    b0 = A * ((A + 1) - (A - 1) * cos(omega_c) + beta * sin(omega_c));
    b1 = 2 * A * ((A - 1) - (A + 1) * cos(omega_c));
    b2 = A * ((A + 1) - (A - 1) * cos(omega_c) - beta * sin(omega_c));
    a0 = (A + 1) + (A - 1) * cos(omega_c) + beta * sin(omega_c);
    a1 = -2 * ((A - 1) + (A + 1) * cos(omega_c));
    a2 = (A + 1) + (A - 1) * cos(omega_c) - beta * sin(omega_c);

    % Normalize coefficients
    b = [b0, b1, b2] / a0;
    a = [a0, a1, a2] / a0;

    % Debugging statements
    disp(['Input signal dimensions: ', num2str(size(input_signal))]);
    disp(['Filter coefficients (b): ', num2str(b)]);
    disp(['Filter coefficients (a): ', num2str(a)]);

    % Ensure input signal is a column vector
    if size(input_signal, 2) > 1
        filtered_signal = zeros(size(input_signal));
        for channel = 1:size(input_signal, 2)
            filtered_signal(:, channel) = filter(b, a, input_signal(:, channel));
        end
    else
        filtered_signal = filter(b, a, input_signal);
    end

    % Debugging statement
    disp(['Filtered signal dimensions: ', num2str(size(filtered_signal))]);
end

    function updatePlot(app)
    % Retrieves current slider values
    gainLow = app.LowFreqGainSlider.Value;
    gainMid = app.MidFreqGainSlider.Value;
    gainHigh = app.HighFreqGainSlider.Value;

    % Filters the signal
    filteredSignal = app.low_freq_shelving_filter(app.song, gainLow, 100, app.Fs, 0.707);
    filteredSignal = app.midEqualizer(filteredSignal, 500, 100, gainMid, app.Fs);
    filteredSignal = app.high_freq_shelving_filter(filteredSignal, gainHigh, 5000, app.Fs, 0.707);

    % Plots frequency response
    [H, W] = freqz(filteredSignal, 1, 2048, app.Fs);
    plot(app.UIAxes, W, 20*log10(abs(H)));
    title(app.UIAxes, 'Combined Frequency Response');
    xlabel(app.UIAxes, 'Frequency (Hz)');
    ylabel(app.UIAxes, 'Magnitude (dB)');
end

   
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: LowFreqGainSlider
        function SliderValueChanging(app, event)
        newGain = event.Value;  % Get the current value of the slider
        app.LowFreqGainNumericEditField.Value = newGain;  % Update the Numeric Edit Field
        updatePlot(app);  % Call a function to update the plot
        end

        % Value changing function: MidFreqGainSlider
        function Slider2ValueChanging(app, event)
          newGain = event.Value;  % Get the current value of the slider
          app.MidFreqGainNumericEditField.Value = newGain;  % Update the Numeric Edit Field
          updatePlot(app);  % Call a function to update the plot
        end

        % Value changing function: HighFreqGainSlider
        function Slider3ValueChanging(app, event)
           newGain = event.Value;  % Get the current value of the slider
           app.HighFreqGainNumericEditField.Value = newGain;  % Update the Numeric Edit Field
           updatePlot(app);  % Call a function to update the plot
        end

        % Button pushed function: PlaysoundButton
        function PlaysoundButtonPushed(app, event)
            % Callback function for the Play Button
    sound(app.filteredSong, app.Fs);  % Play the filtered audio
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
             updatePlot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.LowFreqGainSlider = uislider(app.UIFigure);
            app.LowFreqGainSlider.Limits = [-18 18];
            app.LowFreqGainSlider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider2Label = uilabel(app.UIFigure);
            app.Slider2Label.Text = 'Slider2';
            app.MidFreqGainSlider = uislider(app.UIFigure);
            app.MidFreqGainSlider.Limits = [-18 18];
            app.MidFreqGainSlider.ValueChangingFcn = createCallbackFcn(app, @Slider2ValueChanging, true);
            app.Slider3Label = uilabel(app.UIFigure);
            app.Slider3Label.Text = 'Slider3';
            app.HighFreqGainSlider = uislider(app.UIFigure);
            app.HighFreqGainSlider.Limits = [-18 18];
            app.HighFreqGainSlider.ValueChangingFcn = createCallbackFcn(app, @Slider3ValueChanging, true);
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.LowFreqGainNumericEditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = 'Edit Field';
            app.MidFreqGainNumericEditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Text = 'Edit Field';
            app.HighFreqGainNumericEditField = uieditfield(app.UIFigure, 'numeric');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.PlaysoundButton = uibutton(app.UIFigure, 'push');
            app.PlaysoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlaysoundButtonPushed, true);
            app.PlaysoundButton.Text = 'Play sound';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end